classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        MapTab                         matlab.ui.container.Tab
        OPTIONSPanel                   matlab.ui.container.Panel
        VoxelLengthSliderLabel         matlab.ui.control.Label
        VoxelLengthSlider              matlab.ui.control.Slider
        ofstepsLabel                   matlab.ui.control.Label
        ofstepsEditField               matlab.ui.control.NumericEditField
        StartSimulatorButton           matlab.ui.control.Button
        DeltaTsSliderLabel             matlab.ui.control.Label
        DeltaTsSlider                  matlab.ui.control.Slider
        ofparticlesEditFieldLabel      matlab.ui.control.Label
        ofparticlesEditField           matlab.ui.control.NumericEditField
        RemainingStepsLabel            matlab.ui.control.Label
        remaining_steps                matlab.ui.control.Label
        AnimationScaleLabel            matlab.ui.control.Label
        AnimationScaleSlider           matlab.ui.control.Slider
        ofparticlesLabel               matlab.ui.control.Label
        ofParticles                    matlab.ui.control.Label
        AdvancebyEditFieldLabel        matlab.ui.control.Label
        AdvancebyEditField             matlab.ui.control.NumericEditField
        AdvanceButton                  matlab.ui.control.Button
        SourceVoxelCoordinatesLabel    matlab.ui.control.Label
        XEditFieldLabel                matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        DiffisuoncoefficientDm2sLabel  matlab.ui.control.Label
        DiffisuoncoefficientDm2sEditField  matlab.ui.control.NumericEditField
        particleleakagerateparticlesecLabel  matlab.ui.control.Label
        particleleakagerateparticlesecEditField  matlab.ui.control.NumericEditField
        Switch_2                       matlab.ui.control.Switch
        StopSimulatorButton            matlab.ui.control.Button
        SaveParticleMapButton          matlab.ui.control.Button
        LoadParticleMapButton          matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        Panel_2                        matlab.ui.container.Panel
        SizeofGridEditFieldLabel       matlab.ui.control.Label
        SizeofGridEditField            matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        CalculatingMatricesLabel       matlab.ui.control.Label
        FlowMapTab                     matlab.ui.container.Tab
        FlowOptionsButtonGroup         matlab.ui.container.ButtonGroup
        Switch                         matlab.ui.control.Switch
        Panel                          matlab.ui.container.Panel
        FlowMagnitudeLabel             matlab.ui.control.Label
        FlowMagnitudeSlider            matlab.ui.control.Slider
        PositiveAngleLabel             matlab.ui.control.Label
        PositiveAngleKnob              matlab.ui.control.Knob
        SetFlowButton                  matlab.ui.control.Button
        SaveFlowButton                 matlab.ui.control.Button
        LoadFlowButton                 matlab.ui.control.Button
        RotateLabel                    matlab.ui.control.Label
        RotateEditField                matlab.ui.control.NumericEditField
        SetButton                      matlab.ui.control.Button
        Panel_3                        matlab.ui.container.Panel
        UIAxes_2                       matlab.ui.control.UIAxes
        AngleChangeFactorEditFieldLabel  matlab.ui.control.Label
        AngleChangeFactorEditField     matlab.ui.control.NumericEditField
        MagnitudeDecreaseFactorLabel   matlab.ui.control.Label
        MagnitudeDecreaseFactorEditField  matlab.ui.control.NumericEditField
        DataModificationPanel          matlab.ui.container.Panel
        XEditField_2Label              matlab.ui.control.Label
        XEditField_Flow                matlab.ui.control.NumericEditField
        YEditField_2Label              matlab.ui.control.Label
        YEditField_Flow                matlab.ui.control.NumericEditField
        MagnitudeEditFieldLabel        matlab.ui.control.Label
        MagnitudeEditField             matlab.ui.control.NumericEditField
        AngleindegreesEditFieldLabel   matlab.ui.control.Label
        AngleindegreesEditField        matlab.ui.control.NumericEditField
        VortexSwitchLabel              matlab.ui.control.Label
        VortexSwitch                   matlab.ui.control.Switch
        GetPointButton                 matlab.ui.control.Button
        Vortex_modification_panel      matlab.ui.container.Panel
        XEditField_2Label_2            matlab.ui.control.Label
        XEditField_Flow_2              matlab.ui.control.NumericEditField
        YEditField_2Label_2            matlab.ui.control.Label
        YEditField_Flow_2              matlab.ui.control.NumericEditField
        MagnitudeEditField_2Label      matlab.ui.control.Label
        MagnitudeEditField_2           matlab.ui.control.NumericEditField
        RadiusEditFieldLabel           matlab.ui.control.Label
        RadiusEditField                matlab.ui.control.NumericEditField
        SetPointButton                 matlab.ui.control.Button
        AgentsTab                      matlab.ui.container.Tab
        Panel_4                        matlab.ui.container.Panel
        UIAxes_3                       matlab.ui.control.UIAxes
        AddAgent                       matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        Panel_5                        matlab.ui.container.Panel
        NameEditFieldLabel             matlab.ui.control.Label
        AddAgentNameEditField          matlab.ui.control.EditField
        AddAgentSpeedEditField         matlab.ui.control.NumericEditField
        ParticledetectionrediusLabel   matlab.ui.control.Label
        AddAgentParticledetectionrediusEditField  matlab.ui.control.NumericEditField
        FlowdetectionrediusEditFieldLabel  matlab.ui.control.Label
        AddAgentFlowdetectionrediusEditField  matlab.ui.control.NumericEditField
        XEditField_3Label              matlab.ui.control.Label
        AddAgentXEditField             matlab.ui.control.NumericEditField
        YEditField_3Label              matlab.ui.control.Label
        AddAgentYEditField             matlab.ui.control.NumericEditField
        SensingIntervalEditFieldLabel  matlab.ui.control.Label
        AddAgentSensingIntervalEditField  matlab.ui.control.NumericEditField
        AlgorithmEditFieldLabel        matlab.ui.control.Label
        AlgorithmEditField             matlab.ui.control.NumericEditField
        AddAgentSpeedLabel             matlab.ui.control.Label
        RemoveAgentButton              matlab.ui.control.Button
        AddAgentsaroundacirclePanel    matlab.ui.container.Panel
        RadiusEditField_2Label         matlab.ui.control.Label
        collective_add_agents_radius   matlab.ui.control.NumericEditField
        ofAgentsEditFieldLabel         matlab.ui.control.Label
        collective_add_agents_number   matlab.ui.control.NumericEditField
        collective_add_button          matlab.ui.control.Button
        XEditField_3Label_2            matlab.ui.control.Label
        collective_add_x_coordinate    matlab.ui.control.NumericEditField
        YEditField_3Label_2            matlab.ui.control.Label
        collective_add_y_coordinate    matlab.ui.control.NumericEditField
        RmvallButton                   matlab.ui.control.Button
    end

    
    methods (Access = public)
        
        function result = set_gauge_value(app,value)
            if value==-1
                app.CalculatingMatricesLabel.Visible = "on";
            elseif value == -2
                app.CalculatingMatricesLabel.Visible = "off";
            else
                app.CalculatingMatricesLabel.Text = "Calculating Matrices :%"+ string(value);
            end
        end
        
        function txt = myupdatefcn(~,event_obj)
            % Customizes text of data tips
            pos = get(event_obj,'Position');
            I = get(event_obj, 'DataIndex');
            txt = {['Magnitude: ',num2str(pos(1))],...
                ['Angle(in degrees): ',num2str(pos(2))],...
                };
        end
        
        function results = update(app,isLast)
            global agents flow_matrix meshsize_x particles
            
            if app.AnimationScaleSlider.Value >  0 || isLast
                [x,y] = meshgrid(1:meshsize_x);
                r = flow_matrix(:,:,1);
                theta = (flow_matrix(:,:,2)/360)*2*pi;
                [u,v] = pol2cart(theta,r);
                cla(app.UIAxes_3,"reset");
                cla(app.UIAxes,"reset");
                quiver(app.UIAxes_2,x,y,u,v);
                
                hold(app.UIAxes_3,"on");
                hold(app.UIAxes,"on");
                
                quiver(app.UIAxes_3,x,y,u,v);
                imagesc(app.UIAxes,particles);
                colorbar(app.UIAxes);
                app.ofParticles.Text = string(sum(particles, 'all'));
                agents.show(app.UIAxes_3);
                agents.show(app.UIAxes);
                if app.AnimationScaleSlider.Value >0
                    pause(0.5/app.AnimationScaleSlider.Value);
                end
                
            end
            
        end
    end
    
    
    methods (Access = private)
        
        function results = run_simulator(app,number)
            global stop time agents timer meshsize_x meshsize_y flow_matrix restart particles flow_matrix_x_positive flow_matrix_x_negative flow_matrix_y_positive flow_matrix_y_negative flow_matrix_stay;
            stop = false;
            app.StopSimulatorButton.Visible = true;
            app.StartSimulatorButton.Visible = false;
            
            set_gauge_value(app,-1);
            
            L_voxel = app.VoxelLengthSlider.Value;
            D       = app.DiffisuoncoefficientDm2sEditField.Value;
            
            
            delta_t = app.DeltaTsSlider.Value ;
            env_number_of_particles = app.ofparticlesEditField.Value;
            
            %temp_particles= particles;
            %number_of_steps = app.ofstepsEditField.Value;
            if restart
                [X,Y]=meshgrid(1:meshsize_x+1);
                if app.Switch.Value == "No Flow"
                    is_flow_active = false;
                else
                    is_flow_active=true;
                end
                
                flow_matrix_x_positive = zeros(meshsize_x,meshsize_y);
                flow_matrix_x_negative = zeros(meshsize_x,meshsize_y);
                flow_matrix_y_positive = zeros(meshsize_x,meshsize_y);
                flow_matrix_y_negative = zeros(meshsize_x,meshsize_y);
                flow_matrix_stay = zeros(meshsize_x,meshsize_y);
                set_gauge_value(app,-1);
                
                %% Evaluate transition matrices once and for all
                for column=1:meshsize_y
                    for row=meshsize_x:-1:1
                        [p_stay, p_trans] = eval_probs(is_flow_active,L_voxel,D,delta_t,flow_matrix,row,column);
                        flow_matrix_x_positive(row,column) = p_trans.x_positive;
                        flow_matrix_x_negative(row,column) = p_trans.x_negative;
                        flow_matrix_y_positive(row,column) = p_trans.y_positive;
                        flow_matrix_y_negative(row,column) = p_trans.y_negative;
                        flow_matrix_stay(row,column) = p_stay;
                    end
                    set_gauge_value(app,round((column/meshsize_y)  *100,2));
                    pause(0.001);
                end
                
                %%
                restart = false;
            end
            agents = agents.reset();
            %agents = agents.historyReset(app.ofstepsEditField.Value+1);
            time_cap = app.ofstepsEditField.Value* app.DeltaTsSlider.Value;
            timer = 0;
            time = 0 ;
            taken_steps = 1;
            remaining_steps = app.ofstepsEditField.Value;
            start_stop  = false;
            while time <= time_cap && ~start_stop && (isempty(agents.objects) || ~agents.allFinished())
                %display("time :"+time+" timecap: "+time_cap);
                next_action =0;
                nearest_event_time = timer;
                for i=1:1:length(agents.objects)
                    if agents.objects(i).timer<nearest_event_time
                        next_action = i;
                        nearest_event_time = agents.objects(i).timer;
                    elseif agents.objects(i).timer == nearest_event_time
                        next_action = -1*i;
                    end
                end
                %display("nearest action: "+next_action +" nearest event time: "+ nearest_event_time);
                
                if next_action == 0
                    A = particles.*flow_matrix_x_positive;
                    B = particles.*flow_matrix_x_negative;
                    C = particles.*flow_matrix_y_positive;
                    D = particles.*flow_matrix_y_negative;
                    E = particles.*flow_matrix_stay;
                    particles = E + [zeros(1,meshsize_y) ; D(1:meshsize_x-1,:)] + [C(2:meshsize_x,:) ; zeros(1,meshsize_y)] + [ zeros(meshsize_x,1) , A(:, 1:meshsize_y-1)] + [B(:, 2:meshsize_y) , zeros(meshsize_x,1)];
                    app.ofParticles.Text = string(sum(particles, 'all'));
                    
                    time = timer;
                    timer = time + app.DeltaTsSlider.Value;
                    
                    remaining_steps = remaining_steps-1;
                    app.remaining_steps.Text=""+remaining_steps;
                    update(app,false);
                    taken_steps = taken_steps + 1;
                    
                    particles(app.XEditField.Value,app.YEditField.Value) = particles(app.XEditField.Value,app.YEditField.Value) + (app.particleleakagerateparticlesecEditField.Value * app.DeltaTsSlider.Value);
                    clear isnan;
                    particles(isnan(particles))=0;
                else
                    
                    agents = agents.update(abs(next_action),flow_matrix,particles(max(floor(agents.objects(abs(next_action)).x_coordinate)-agents.objects(abs(next_action)).particleDetectionRadius,0):min(floor(agents.objects(abs(next_action)).x_coordinate)+agents.objects(abs(next_action)).particleDetectionRadius,meshsize_x), max(floor(agents.objects(abs(next_action)).y_coordinate)-agents.objects(abs(next_action)).particleDetectionRadius,0):min(floor(agents.objects(abs(next_action)).y_coordinate)+agents.objects(abs(next_action)).particleDetectionRadius,meshsize_y)),taken_steps,meshsize_x,meshsize_y,app.AnimationScaleSlider.Value);
                    on_off = true;
                    if app.AnimationScaleSlider.Value ==0
                        on_off = false;
                    end
                    agents.AgentVision(abs(next_action),particles,meshsize_x,meshsize_y,false,on_off);
                    if next_action > 0
                        time = agents.objects(next_action).timer;
                    end
                    agents =  agents.advanceTimer(abs(next_action));
                    %display(next_action + "'s timer: "+agents.objects(abs(next_action)).timer);
                    update(app,false);
                end
                start_stop = stop;
                pause(0.01);
            end
            if agents.allFinished()
                agents.reportAll(app.XEditField.Value,app.YEditField.Value,number);
            end
            %             imagesc(app.UIAxes,particles);
            %             colorbar(app.UIAxes);
            update(app,true);
            app.remaining_steps.Text = string(0);
            app.ofParticles.Text = string(sum(particles, 'all'));
            app.StartSimulatorButton.Visible = true;
            %display(particles);
            set_gauge_value(app,-2);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            global time timer agents meshsize_x meshsize_y flow_matrix restart particles flow_matrix_x_positive flow_matrix_x_negative flow_matrix_y_positive flow_matrix_y_negative flow_matrix_stay;
            agents = List;
            meshsize_x = app.SizeofGridEditField.Value;
            meshsize_y = app.SizeofGridEditField.Value;
            flow_matrix= zeros(meshsize_x,meshsize_y,2);
            restart = true;
            particles = zeros(meshsize_x,meshsize_y);
  
            app.StopSimulatorButton.Visible = false ;
            app.Vortex_modification_panel.Visible = 'off';
            app.XEditField.Value = floor(app.SizeofGridEditField.Value/2);
            app.YEditField.Value = floor(app.SizeofGridEditField.Value/2);
            app.CalculatingMatricesLabel.Visible = 'off';
            flow_matrix_x_positive = zeros(meshsize_x,meshsize_y);
            flow_matrix_x_negative = zeros(meshsize_x,meshsize_y);
            flow_matrix_y_positive = zeros(meshsize_x,meshsize_y);
            flow_matrix_y_negative = zeros(meshsize_x,meshsize_y);
            flow_matrix_stay = zeros(meshsize_x,meshsize_y);
            time  = 0 ;
            
        end

        % Button pushed function: StartSimulatorButton
        function StartSimulatorButtonPushed(app, event)
            run_simulator(app,1);
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            global meshsize_x meshsize_y flow_matrix;
            %This function gives a random flow matrix
            % matrix length is given as input
            disp(value)
            if value == "No Flow"
                
                flow_matrix= zeros(meshsize_x,meshsize_y,2);
                app.Panel.Visible = 'off' ;
            end
            if value == "With Flow"
                app.Panel.Visible = 'on' ;
                
            end
            
        end

        % Value changed function: SizeofGridEditField
        function SizeofGridEditFieldValueChanged(app, event)
            value = app.SizeofGridEditField.Value;
            global meshsize_x meshsize_y flow_matrix;
            meshsize_x = value;
            meshsize_y = value;
            flow_matrix= zeros(meshsize_x,meshsize_y,2);
            app.XEditField.Value = floor(app.SizeofGridEditField.Value/2);
            app.YEditField.Value = floor(app.SizeofGridEditField.Value/2);
            
        end

        % Size changed function: FlowMapTab
        function FlowMapTabSizeChanged(app, event)
            position = app.FlowMapTab.Position;
            
        end

        % Button pushed function: SetFlowButton
        function SetFlowButtonPushed(app, event)
            global meshsize_x meshsize_y flow_matrix restart;
            
            %             app.UIAxes_2.ButtonDownFcn=@mouseClick;
            %             function mouseClick(~,~)
            %                 cp=get(app.UIAxes,'CurrentPoint');
            %                 display(cp(1,1));
            %             end
            %             dcm_obj = datacursormode(app.UIAxes_2.);
            %             set(dcm_obj,'DisplayStyle','datatip','SnapToDataVertex','off','Enable','on');
            %             set(dcm_obj,'UpdateFcn',{@myupdatefcn});
            
            meshsize_x = app.SizeofGridEditField.Value;
            meshsize_y = app.SizeofGridEditField.Value;
            if app.Switch.Value == "With Flow"
                magnitude = app.FlowMagnitudeSlider.Value;
                %disp(magnitude)
                magnitude_decrease_factor=app.MagnitudeDecreaseFactorEditField.Value;
                angle_change_factor=app.AngleChangeFactorEditField.Value;
                angle = app.PositiveAngleKnob.Value ;
                
                flow_matrix(meshsize_x,meshsize_y,1)= magnitude;
                flow_matrix(meshsize_x,meshsize_y,2)= angle;
                
                for column=meshsize_y:-1:1
                    for row=meshsize_x:-1:1
                        if row==meshsize_x && column== meshsize_y
                        elseif row==meshsize_x && column~= meshsize_y
                            flow_matrix(row,column,1)= flow_matrix(row,column+1,1);
                            flow_matrix(row,column,2)= flow_matrix(row,column+1,2)+normrnd(0,angle_change_factor);
                        elseif row ~= meshsize_x
                            flow_matrix(row,column,1)= flow_matrix(row+1,column,1)*(1+magnitude_decrease_factor * (0.1).*rand(1,1));
                            flow_matrix(row,column,2)= flow_matrix(row+1,column,2)+normrnd(0,angle_change_factor);
                        end
                    end
                end
            end
            %disp(flow_matrix)
            update(app,false);
            restart = true;
            
        end

        % Button pushed function: AdvanceButton
        function AdvanceButtonPushed(app, event)
            global restart meshsize_x meshsize_y particles flow_matrix_x_positive flow_matrix_x_negative flow_matrix_y_positive flow_matrix_y_negative flow_matrix_stay;
            
            if ~restart
                app.remaining_steps.Text  = string(app.AdvancebyEditField.Value);
                for i=app.AdvancebyEditField.Value:-1:1
                    A = particles.*flow_matrix_x_positive;
                    B = particles.*flow_matrix_x_negative;
                    C = particles.*flow_matrix_y_positive;
                    D = particles.*flow_matrix_y_negative;
                    E = particles.*flow_matrix_stay;
                    particles = E + [zeros(1,meshsize_y) ; D(1:meshsize_x-1,:)] + [C(2:meshsize_x,:) ; zeros(1,meshsize_y)] + [ zeros(meshsize_x,1) , A(:, 1:meshsize_y-1)] + [B(:, 2:meshsize_y) , zeros(meshsize_x,1)];
                    %disp(i)
                    
                    update(app,false);
                end
                imagesc(app.UIAxes,particles)
                colorbar
                app.remaining_steps.Text = string(0);
                app.ofParticles.Text = string(sum(particles, 'all'));
            end
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            value = app.XEditField.Value;
            if value > app.SizeofGridEditField.Value
                app.XEditField.Value = app.SizeofGridEditField.Value;
                app.YEditField.Value = app.SizeofGridEditField.Value;
            end
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            value = app.YEditField.Value;
            if value > app.SizeofGridEditField.Value
                app.XEditField.Value = app.SizeofGridEditField.Value;
                app.YEditField.Value = app.SizeofGridEditField.Value;
            end
            
        end

        % Button pushed function: GetPointButton
        function GetPointButtonPushed(app, event)
            global flow_matrix;
            %display(app.UIAxes_2.CurrentPoint)
            app.XEditField_Flow.Value = (round(app.UIAxes_2.CurrentPoint(1,1),0));
            app.YEditField_Flow.Value = (round(app.UIAxes_2.CurrentPoint(1,2),0));
            app.MagnitudeEditField.Value = flow_matrix(app.XEditField_Flow.Value,app.YEditField_Flow.Value,1);
            app.AngleindegreesEditField.Value  = flow_matrix(app.XEditField_Flow.Value,app.YEditField_Flow.Value,2);
        end

        % Button pushed function: SetPointButton
        function SetPointButtonPushed(app, event)
            global flow_matrix restart meshsize_x meshsize_y;
            if app.VortexSwitch.Value == "Off"
                flow_matrix(round(app.YEditField_Flow.Value,0),round(app.XEditField_Flow.Value,0),1) =  app.MagnitudeEditField.Value;
                flow_matrix(round(app.YEditField_Flow.Value,0),round(app.XEditField_Flow.Value,0),2) =  app.AngleindegreesEditField.Value;
                restart = true;
            else
                for i=1:1:15
                    for j=1:1:round(i/2,0)
                   % flow_matrix(round(app.YEditField_Flow_2.Value,0)+i,round(app.XEditField_Flow_2.Value+j,0),1) =  app.MagnitudeEditField.Value;
                    flow_matrix(round(app.YEditField_Flow_2.Value,0)+i,round(app.XEditField_Flow_2.Value+j*sign(app.RadiusEditField.Value),0),2) =  flow_matrix(round(app.YEditField_Flow_2.Value,0)+i,round(app.XEditField_Flow_2.Value+j*sign(app.RadiusEditField.Value),0),2)+ j*(-1)*app.RadiusEditField.Value;
                    end
                end
            end
            update(app,true);
        end

        % Value changed function: VortexSwitch
        function VortexSwitchValueChanged(app, event)
            value = app.VortexSwitch.Value;
            if value == "On"
                app.DataModificationPanel.Visible = 'off';
                app.Vortex_modification_panel.Visible = 'on';
            else
                app.DataModificationPanel.Visible = 'on';
                app.Vortex_modification_panel.Visible = 'off';
            end
            
        end

        % Button pushed function: AddAgent
        function AddAgentPushed(app, event)
            global agents
            agents = agents.append( Agent(app.AddAgentNameEditField.Value,app.AddAgentSpeedEditField.Value,app.AddAgentParticledetectionrediusEditField.Value,app.AddAgentFlowdetectionrediusEditField.Value,app.AddAgentXEditField.Value,app.AddAgentYEditField.Value,app.AddAgentSensingIntervalEditField.Value,app.AlgorithmEditField.Value));

            app.UITable.Data  = [ app.UITable.Data ; ""+app.AddAgentNameEditField.Value ];
            update(app,true);
         
        end

        % Button pushed function: RemoveAgentButton
        function RemoveAgentButtonPushed(app, event)
            global agents selectedAgentName selectedAgentIndices
            agents = agents.remove(selectedAgentName);
            if length(app.UITable.Data)>1
                app.UITable.Data(selectedAgentIndices(1,1)) = [] ;
            else
                app.UITable.Data = [] ;
            end
   
            update(app,true);
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            global agents selectedAgentName selectedAgentIndices meshsize_x meshsize_y
            
            selectedAgentIndices = event.Indices;
            selectedAgentName= app.UITable.Data(selectedAgentIndices(1,1));
            app.AddAgentNameEditField.Value = agents.objects(selectedAgentIndices(1,1)).name;
            app.AddAgentSpeedEditField.Value = agents.objects(selectedAgentIndices(1,1)).speed;
            app.AddAgentParticledetectionrediusEditField.Value = agents.objects(selectedAgentIndices(1,1)).particleDetectionRadius;
            app.AddAgentFlowdetectionrediusEditField.Value = agents.objects(selectedAgentIndices(1,1)).flowDetectionRadius;
            app.AddAgentXEditField.Value = agents.objects(selectedAgentIndices(1,1)).init_x_coordinate;
            app.AddAgentYEditField.Value = agents.objects(selectedAgentIndices(1,1)).init_y_coordinate;
            app.AddAgentSensingIntervalEditField.Value = agents.objects(selectedAgentIndices(1,1)).sensingTimeInterval;
            
            agent_x_coord = agents.objects(selectedAgentIndices(1,1)).x_coordinate  ;
            agent_y_coord = agents.objects(selectedAgentIndices(1,1)).y_coordinate ;
            app.UIAxes_3.XLim = [agent_x_coord-2, agent_x_coord+2];
            app.UIAxes_3.YLim = [agent_y_coord-2, agent_y_coord+2];
            %             app.UIAxes_3.CameraPosition =  [agents.objects(selectedAgentIndices(1,1)).x_coordinate agents.objects(selectedAgentIndices(1,1)).y_coordinate 0.5] ;
            %             app.UIAxes_3.CameraTarget =  [agents.objects(selectedAgentIndices(1,1)).x_coordinate agents.objects(selectedAgentIndices(1,1)).y_coordinate 0] ;
            %
            
        end

        % Button pushed function: SaveFlowButton
        function SaveFlowButtonPushed(app, event)
            global flow_matrix
            prompt = {'Enter file name:'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'*.xls'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);

            if ~isempty(answer)
                writematrix(flow_matrix,string(answer(1)));
            end
            
        end

        % Button pushed function: LoadFlowButton
        function LoadFlowButtonPushed(app, event)
            global flow_matrix meshsize_x meshsize_y
            prompt = {'Enter file name:'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'*.xls'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if ~isempty(answer)
                temp_matrix = readmatrix(string(answer(1)));
                dimension = length(temp_matrix(:,1));
                meshsize_x = dimension;
                meshsize_y = dimension;
                flow_matrix = zeros(dimension,dimension,2);
                flow_matrix(:,:,1) = temp_matrix(1:dimension,1:dimension);
                flow_matrix(:,:,2) = temp_matrix(1:dimension,dimension+1:dimension*2);

                app.SizeofGridEditField.Value = dimension;
                app.XEditField.Value = floor(app.SizeofGridEditField.Value/2);
                app.YEditField.Value = floor(app.SizeofGridEditField.Value/2);
                update(app,true);
            end
        end

        % Callback function
        function Switch_2ValueChanged(app, event)
            global restart
            value = app.Switch_2.Value;
            
            restart = true;
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            global flow_matrix
%           flow_matrix(:,:,2) =  flow_matrix(:,:,2)+ app.RotateEditField.Value;
            flow_matrix(:,:,2) = flow_matrix(:,:,2).' ;
            update(app,true);
        end

        % Value changed function: DiffisuoncoefficientDm2sEditField
        function DiffisuoncoefficientDm2sEditFieldValueChanged(app, event)
            global restart
            value = app.DiffisuoncoefficientDm2sEditField.Value;
            restart = true;
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged2(app, event)
            global restart
            restart = true;
            value = app.Switch_2.Value;
            if value == "m"
                app.DeltaTsSlider.Value = 1;
                app.DiffisuoncoefficientDm2sLabel.Text = compose("Diffisuon coefficient \n(D) m^2/s)");
                app.DiffisuoncoefficientDm2sEditField.Value = power(10,-9);
                app.VoxelLengthSlider.Value = 1;
            else
                app.DeltaTsSlider.Value = 0.001;
                app.DiffisuoncoefficientDm2sLabel.Text = compose("Diffisuon coefficient \n(D) μm^2/s)");
                app.DiffisuoncoefficientDm2sEditField.Value = power(10,2);
                app.VoxelLengthSlider.Value = 0.25;
            end
        end

        % Value changed function: DeltaTsSlider
        function DeltaTsSliderValueChanged(app, event)
            global restart
            restart = true;
            value = app.DeltaTsSlider.Value;
            
        end

        % Button pushed function: StopSimulatorButton
        function StopSimulatorButtonPushed(app, event)
            global stop
            stop = true;
            display("durdur")
            app.StopSimulatorButton.Visible = false;
            
        end

        % Button pushed function: SaveParticleMapButton
        function SaveParticleMapButtonPushed(app, event)

            global particles
            prompt = {'Enter file name:'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'*.xls'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);

            if ~isempty(answer)
                writematrix(particles,string(answer(1)));
            end
         
        end

        % Button pushed function: LoadParticleMapButton
        function LoadParticleMapButtonPushed(app, event)
            global particles meshsize_x meshsize_y flow_matrix
            prompt = {'Enter file name:'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'*.xls'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if ~isempty(answer)
                temp_matrix = readmatrix(string(answer(1)));
                dimension = length(temp_matrix(:,1));
                meshsize_x = dimension;
                meshsize_y = dimension;
                particles = temp_matrix;
                myPath = string(answer(1));
                myPath = split(myPath,"_");
                try
                    temp_matrix = readmatrix(myPath(1)+"_"+string(myPath(2))+".xls");
                    
                    display(myPath(1)+"_"+string(myPath(2))+".xls");
                    dimension = length(temp_matrix(:,1));
                    meshsize_x = dimension;
                    meshsize_y = dimension;
                    flow_matrix = zeros(dimension,dimension,2);
                    flow_matrix(:,:,1) = temp_matrix(1:dimension,1:dimension);
                    flow_matrix(:,:,2) = temp_matrix(1:dimension,dimension+1:dimension*2);
                catch
                    
                end

                app.SizeofGridEditField.Value = dimension;
                app.XEditField.Value = floor(app.SizeofGridEditField.Value/2);
                app.YEditField.Value = floor(app.SizeofGridEditField.Value/2);
                update(app,true);
            end
        end

        % Button pushed function: collective_add_button
        function collective_add_buttonButtonPushed(app, event)
            
            global agents
            for i=1:1:app.collective_add_agents_number.Value
                agents = agents.append( Agent(app.AddAgentNameEditField.Value+"-"+string(i),app.AddAgentSpeedEditField.Value,app.AddAgentParticledetectionrediusEditField.Value,app.AddAgentFlowdetectionrediusEditField.Value,app.collective_add_x_coordinate.Value+app.collective_add_agents_radius.Value*cos((i*2*pi)/app.collective_add_agents_number.Value),app.collective_add_y_coordinate.Value+app.collective_add_agents_radius.Value*sin((i*2*pi)/app.collective_add_agents_number.Value),app.AddAgentSensingIntervalEditField.Value,app.AlgorithmEditField.Value));
                app.UITable.Data  = [ app.UITable.Data ; ""+app.AddAgentNameEditField.Value+"-"+string(i) ];
            end
            update(app,true);
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            global particles meshsize_x meshsize_y
            particles = zeros(meshsize_x,meshsize_y);
            particles(floor(meshsize_x/2),floor(meshsize_y/2))= app.ofparticlesEditField.Value;
            update(app,false);
        end

        % Button pushed function: RmvallButton
        function RmvallButtonPushed(app, event)
            global agents selectedAgentName selectedAgentIndices
            agents = agents.remove(selectedAgentName);
            agents = List;
            app.UITable.Data = [] ;
            update(app,true);
            
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global particles agents agents selectedAgentName selectedAgentIndices  meshsize_x meshsize_y flow_matrix
            prompt = {'Enter file name:'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'*.xls'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            
            
            for x=1:1:50
                agents = agents.remove(selectedAgentName);
                agents = List;
                app.UITable.Data = [] ;
                for i=1:1:app.collective_add_agents_number.Value
                    agents = agents.append( Agent(app.AddAgentNameEditField.Value+"-"+string(i),app.AddAgentSpeedEditField.Value,app.AddAgentParticledetectionrediusEditField.Value,app.AddAgentFlowdetectionrediusEditField.Value,app.collective_add_x_coordinate.Value+app.collective_add_agents_radius.Value*cos((i*2*pi)/app.collective_add_agents_number.Value),app.collective_add_y_coordinate.Value+app.collective_add_agents_radius.Value*sin((i*2*pi)/app.collective_add_agents_number.Value),app.AddAgentSensingIntervalEditField.Value,app.AlgorithmEditField.Value));
                    app.UITable.Data  = [ app.UITable.Data ; ""+app.AddAgentNameEditField.Value+"-"+string(i) ];
                end
                if ~isempty(answer)
                    temp_matrix = readmatrix(string(answer(1)));
                    dimension = length(temp_matrix(:,1));
                    meshsize_x = dimension;
                    meshsize_y = dimension;
                    particles = temp_matrix;
                    myPath = string(answer(1));
                    myPath = split(myPath,"_");
                    try
                        temp_matrix = readmatrix(myPath(1)+"_"+string(myPath(2))+".xls");
                        
                        display(myPath(1)+"_"+string(myPath(2))+".xls");
                        dimension = length(temp_matrix(:,1));
                        meshsize_x = dimension;
                        meshsize_y = dimension;
                        flow_matrix = zeros(dimension,dimension,2);
                        flow_matrix(:,:,1) = temp_matrix(1:dimension,1:dimension);
                        flow_matrix(:,:,2) = temp_matrix(1:dimension,dimension+1:dimension*2);
                    catch
                        
                    end
                    
                    app.SizeofGridEditField.Value = dimension;
                    app.XEditField.Value = floor(app.SizeofGridEditField.Value/2);
                    app.YEditField.Value = floor(app.SizeofGridEditField.Value/2);
                    update(app,true);
                end
                
                update(app,true);
                run_simulator(app,x);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MapTab = uitab(app.TabGroup);
            app.MapTab.Title = 'Map';
            app.OPTIONSPanel = uipanel(app.MapTab);
            app.OPTIONSPanel.Title = 'OPTIONS';
            app.VoxelLengthSliderLabel = uilabel(app.OPTIONSPanel);
            app.VoxelLengthSliderLabel.Text = {'Voxel Length'; ''};
            app.VoxelLengthSlider = uislider(app.OPTIONSPanel);
            app.VoxelLengthSlider.Limits = [0.25 2];
            app.VoxelLengthSlider.Value = 0.25;
            app.ofstepsLabel = uilabel(app.OPTIONSPanel);
            app.ofstepsLabel.Text = '# of steps';
            app.ofstepsEditField = uieditfield(app.OPTIONSPanel, 'numeric');
            app.ofstepsEditField.ValueDisplayFormat = '%.0f';
            app.ofstepsEditField.Value = 1;
            app.StartSimulatorButton = uibutton(app.OPTIONSPanel, 'push');
            app.StartSimulatorButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulatorButtonPushed, true);
            app.StartSimulatorButton.Text = 'Start Simulator';
            app.DeltaTsSliderLabel = uilabel(app.OPTIONSPanel);
            app.DeltaTsSliderLabel.Text = 'Delta T (s)';
            app.DeltaTsSlider = uislider(app.OPTIONSPanel);
            app.DeltaTsSlider.Limits = [0.001 2];
            app.DeltaTsSlider.ValueChangedFcn = createCallbackFcn(app, @DeltaTsSliderValueChanged, true);
            app.DeltaTsSlider.Value = 0.001;
            app.ofparticlesEditFieldLabel = uilabel(app.OPTIONSPanel);
            app.ofparticlesEditFieldLabel.Text = '# of particles';
            app.ofparticlesEditField = uieditfield(app.OPTIONSPanel, 'numeric');
            app.ofparticlesEditField.ValueDisplayFormat = '%.0f';
            app.RemainingStepsLabel = uilabel(app.OPTIONSPanel);
            app.RemainingStepsLabel.Text = 'Remaining Steps: ';
            app.remaining_steps = uilabel(app.OPTIONSPanel);
            app.remaining_steps.Text = '0';
            app.AnimationScaleLabel = uilabel(app.OPTIONSPanel);
            app.AnimationScaleLabel.Text = {'Animation '; 'Scale'};
            app.AnimationScaleSlider = uislider(app.OPTIONSPanel);
            app.AnimationScaleSlider.Limits = [0 10];
            app.ofparticlesLabel = uilabel(app.OPTIONSPanel);
            app.ofparticlesLabel.Text = '# of particles:';
            app.ofParticles = uilabel(app.OPTIONSPanel);
            app.ofParticles.Text = '0';
            app.AdvancebyEditFieldLabel = uilabel(app.OPTIONSPanel);
            app.AdvancebyEditFieldLabel.Visible = 'off';
            app.AdvancebyEditFieldLabel.Text = 'Advance by';
            app.AdvancebyEditField = uieditfield(app.OPTIONSPanel, 'numeric');
            app.AdvancebyEditField.Visible = 'off';
            app.AdvancebyEditField.Value = 1;
            app.AdvanceButton = uibutton(app.OPTIONSPanel, 'push');
            app.AdvanceButton.ButtonPushedFcn = createCallbackFcn(app, @AdvanceButtonPushed, true);
            app.AdvanceButton.Visible = 'off';
            app.AdvanceButton.Text = 'Advance';
            app.SourceVoxelCoordinatesLabel = uilabel(app.OPTIONSPanel);
            app.SourceVoxelCoordinatesLabel.Text = 'Source Voxel Coordinates:';
            app.XEditFieldLabel = uilabel(app.OPTIONSPanel);
            app.XEditFieldLabel.Text = 'X:';
            app.XEditField = uieditfield(app.OPTIONSPanel, 'numeric');
            app.XEditField.ValueDisplayFormat = '%.0f';
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.OPTIONSPanel);
            app.YEditFieldLabel.Text = 'Y:';
            app.YEditField = uieditfield(app.OPTIONSPanel, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.DiffisuoncoefficientDm2sLabel = uilabel(app.OPTIONSPanel);
            app.DiffisuoncoefficientDm2sLabel.Text = {'Diffisuon coefficient '; '(D) (μm^2/s)'};
            app.DiffisuoncoefficientDm2sEditField = uieditfield(app.OPTIONSPanel, 'numeric');
            app.DiffisuoncoefficientDm2sEditField.ValueChangedFcn = createCallbackFcn(app, @DiffisuoncoefficientDm2sEditFieldValueChanged, true);
            app.DiffisuoncoefficientDm2sEditField.Value = 100;
            app.particleleakagerateparticlesecLabel = uilabel(app.OPTIONSPanel);
            app.particleleakagerateparticlesecLabel.Text = {'particle leakage rate'; '(particle/sec)'};
            app.particleleakagerateparticlesecEditField = uieditfield(app.OPTIONSPanel, 'numeric');
            app.particleleakagerateparticlesecEditField.ValueDisplayFormat = '%.0f';
            app.particleleakagerateparticlesecEditField.Value = 100000;
            app.Switch_2 = uiswitch(app.OPTIONSPanel, 'slider');
            app.Switch_2.Items = {'m', ' μm'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged2, true);
            app.Switch_2.Value = ' μm';
            app.StopSimulatorButton = uibutton(app.OPTIONSPanel, 'push');
            app.StopSimulatorButton.ButtonPushedFcn = createCallbackFcn(app, @StopSimulatorButtonPushed, true);
            app.StopSimulatorButton.Text = 'Stop Simulator';
            app.SaveParticleMapButton = uibutton(app.OPTIONSPanel, 'push');
            app.SaveParticleMapButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParticleMapButtonPushed, true);
            app.SaveParticleMapButton.Text = 'Save Particle Map';
            app.LoadParticleMapButton = uibutton(app.OPTIONSPanel, 'push');
            app.LoadParticleMapButton.ButtonPushedFcn = createCallbackFcn(app, @LoadParticleMapButtonPushed, true);
            app.LoadParticleMapButton.Text = 'Load Particle Map';
            app.ResetButton = uibutton(app.OPTIONSPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = '<Reset>';
            app.Button = uibutton(app.OPTIONSPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Panel_2 = uipanel(app.MapTab);
            app.SizeofGridEditFieldLabel = uilabel(app.Panel_2);
            app.SizeofGridEditFieldLabel.Text = 'Size of Grid ';
            app.SizeofGridEditField = uieditfield(app.Panel_2, 'numeric');
            app.SizeofGridEditField.ValueChangedFcn = createCallbackFcn(app, @SizeofGridEditFieldValueChanged, true);
            app.SizeofGridEditField.Value = 40;
            app.UIAxes = uiaxes(app.Panel_2);
            title(app.UIAxes, 'Particles')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, {'Y'; ''})
            app.CalculatingMatricesLabel = uilabel(app.MapTab);
            app.CalculatingMatricesLabel.Visible = 'off';
            app.CalculatingMatricesLabel.Text = 'Calculating Matrices :% ';
            app.FlowMapTab = uitab(app.TabGroup);
            app.FlowMapTab.SizeChangedFcn = createCallbackFcn(app, @FlowMapTabSizeChanged, true);
            app.FlowMapTab.Title = 'Flow Map';
            app.FlowOptionsButtonGroup = uibuttongroup(app.FlowMapTab);
            app.FlowOptionsButtonGroup.Title = 'Flow Options';
            app.Switch = uiswitch(app.FlowOptionsButtonGroup, 'slider');
            app.Switch.Items = {'No Flow', 'With Flow'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'With Flow';
            app.Panel = uipanel(app.FlowMapTab);
            app.FlowMagnitudeLabel = uilabel(app.Panel);
            app.FlowMagnitudeLabel.Text = {'Flow '; 'Magnitude'};
            app.FlowMagnitudeSlider = uislider(app.Panel);
            app.FlowMagnitudeSlider.Limits = [0 1000];
            app.FlowMagnitudeSlider.Value = 250;
            app.PositiveAngleLabel = uilabel(app.Panel);
            app.PositiveAngleLabel.Text = 'Positive Angle (°)';
            app.PositiveAngleKnob = uiknob(app.Panel, 'continuous');
            app.PositiveAngleKnob.Limits = [0 360];
            app.SetFlowButton = uibutton(app.Panel, 'push');
            app.SetFlowButton.ButtonPushedFcn = createCallbackFcn(app, @SetFlowButtonPushed, true);
            app.SetFlowButton.Text = 'Set Flow';
            app.SaveFlowButton = uibutton(app.Panel, 'push');
            app.SaveFlowButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFlowButtonPushed, true);
            app.SaveFlowButton.Text = 'Save Flow';
            app.LoadFlowButton = uibutton(app.Panel, 'push');
            app.LoadFlowButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFlowButtonPushed, true);
            app.LoadFlowButton.Text = 'Load Flow';
            app.RotateLabel = uilabel(app.Panel);
            app.RotateLabel.Text = 'Rotate (°)';
            app.RotateEditField = uieditfield(app.Panel, 'numeric');
            app.SetButton = uibutton(app.Panel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.Panel_3 = uipanel(app.FlowMapTab);
            app.UIAxes_2 = uiaxes(app.Panel_3);
            title(app.UIAxes_2, 'Flow Map')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.AngleChangeFactorEditFieldLabel = uilabel(app.Panel_3);
            app.AngleChangeFactorEditFieldLabel.Text = 'Angle Change Factor ()';
            app.AngleChangeFactorEditField = uieditfield(app.Panel_3, 'numeric');
            app.AngleChangeFactorEditField.Value = 0.0005;
            app.MagnitudeDecreaseFactorLabel = uilabel(app.Panel_3);
            app.MagnitudeDecreaseFactorLabel.Text = {'Magnitude '; 'Decrease Factor'};
            app.MagnitudeDecreaseFactorEditField = uieditfield(app.Panel_3, 'numeric');
            app.MagnitudeDecreaseFactorEditField.Value = 1;
            app.DataModificationPanel = uipanel(app.FlowMapTab);
            app.DataModificationPanel.Title = 'Data Modification Panel';
            app.XEditField_2Label = uilabel(app.DataModificationPanel);
            app.XEditField_2Label.Text = 'X:';
            app.XEditField_Flow = uieditfield(app.DataModificationPanel, 'numeric');
            app.YEditField_2Label = uilabel(app.DataModificationPanel);
            app.YEditField_2Label.Text = 'Y:';
            app.YEditField_Flow = uieditfield(app.DataModificationPanel, 'numeric');
            app.MagnitudeEditFieldLabel = uilabel(app.DataModificationPanel);
            app.MagnitudeEditFieldLabel.Text = 'Magnitude:';
            app.MagnitudeEditField = uieditfield(app.DataModificationPanel, 'numeric');
            app.AngleindegreesEditFieldLabel = uilabel(app.DataModificationPanel);
            app.AngleindegreesEditFieldLabel.Text = 'Angle(in degrees)';
            app.AngleindegreesEditField = uieditfield(app.DataModificationPanel, 'numeric');
            app.VortexSwitchLabel = uilabel(app.FlowMapTab);
            app.VortexSwitchLabel.Text = 'Vortex';
            app.VortexSwitch = uiswitch(app.FlowMapTab, 'slider');
            app.VortexSwitch.ValueChangedFcn = createCallbackFcn(app, @VortexSwitchValueChanged, true);
            app.GetPointButton = uibutton(app.FlowMapTab, 'push');
            app.GetPointButton.ButtonPushedFcn = createCallbackFcn(app, @GetPointButtonPushed, true);
            app.GetPointButton.Text = 'Get Point';
            app.Vortex_modification_panel = uipanel(app.FlowMapTab);
            app.Vortex_modification_panel.Title = 'Vortex Modif. Panel';
            app.XEditField_2Label_2 = uilabel(app.Vortex_modification_panel);
            app.XEditField_2Label_2.Text = 'X:';
            app.XEditField_Flow_2 = uieditfield(app.Vortex_modification_panel, 'numeric');
            app.YEditField_2Label_2 = uilabel(app.Vortex_modification_panel);
            app.YEditField_2Label_2.Text = 'Y:';
            app.YEditField_Flow_2 = uieditfield(app.Vortex_modification_panel, 'numeric');
            app.MagnitudeEditField_2Label = uilabel(app.Vortex_modification_panel);
            app.MagnitudeEditField_2Label.Text = 'Magnitude:';
            app.MagnitudeEditField_2 = uieditfield(app.Vortex_modification_panel, 'numeric');
            app.RadiusEditFieldLabel = uilabel(app.Vortex_modification_panel);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.Vortex_modification_panel, 'numeric');
            app.SetPointButton = uibutton(app.FlowMapTab, 'push');
            app.SetPointButton.ButtonPushedFcn = createCallbackFcn(app, @SetPointButtonPushed, true);
            app.SetPointButton.Text = 'Set Point';
            app.AgentsTab = uitab(app.TabGroup);
            app.AgentsTab.Title = 'Agents';
            app.Panel_4 = uipanel(app.AgentsTab);
            app.UIAxes_3 = uiaxes(app.Panel_4);
            title(app.UIAxes_3, 'Flow Map')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.AddAgent = uibutton(app.AgentsTab, 'push');
            app.AddAgent.ButtonPushedFcn = createCallbackFcn(app, @AddAgentPushed, true);
            app.AddAgent.Text = 'Add Agent';
            app.UITable = uitable(app.AgentsTab);
            app.UITable.ColumnName = {'Column 1'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.Panel_5 = uipanel(app.AgentsTab);
            app.NameEditFieldLabel = uilabel(app.Panel_5);
            app.NameEditFieldLabel.Text = 'Name';
            app.AddAgentNameEditField = uieditfield(app.Panel_5, 'text');
            app.AddAgentNameEditField.Value = 'Agent';
            app.AddAgentSpeedEditField = uieditfield(app.Panel_5, 'numeric');
            app.AddAgentSpeedEditField.Value = 2;
            app.ParticledetectionrediusLabel = uilabel(app.Panel_5);
            app.ParticledetectionrediusLabel.Text = {'Particle '; 'detection redius'};
            app.AddAgentParticledetectionrediusEditField = uieditfield(app.Panel_5, 'numeric');
            app.AddAgentParticledetectionrediusEditField.Value = 2;
            app.FlowdetectionrediusEditFieldLabel = uilabel(app.Panel_5);
            app.FlowdetectionrediusEditFieldLabel.Text = {'Flow'; 'detection redius'};
            app.AddAgentFlowdetectionrediusEditField = uieditfield(app.Panel_5, 'numeric');
            app.AddAgentFlowdetectionrediusEditField.Value = 2;
            app.XEditField_3Label = uilabel(app.Panel_5);
            app.XEditField_3Label.Text = 'X:';
            app.AddAgentXEditField = uieditfield(app.Panel_5, 'numeric');
            app.AddAgentXEditField.Value = 10;
            app.YEditField_3Label = uilabel(app.Panel_5);
            app.YEditField_3Label.Text = 'Y:';
            app.AddAgentYEditField = uieditfield(app.Panel_5, 'numeric');
            app.AddAgentYEditField.Value = 10;
            app.SensingIntervalEditFieldLabel = uilabel(app.Panel_5);
            app.SensingIntervalEditFieldLabel.Text = 'Sensing Interval';
            app.AddAgentSensingIntervalEditField = uieditfield(app.Panel_5, 'numeric');
            app.AddAgentSensingIntervalEditField.Value = 0.001;
            app.AlgorithmEditFieldLabel = uilabel(app.Panel_5);
            app.AlgorithmEditFieldLabel.Text = 'Algorithm';
            app.AlgorithmEditField = uieditfield(app.Panel_5, 'numeric');
            app.AlgorithmEditField.Value = 1;
            app.AddAgentSpeedLabel = uilabel(app.Panel_5);
            app.AddAgentSpeedLabel.Text = 'Speed(voxel/sec)';
            app.RemoveAgentButton = uibutton(app.AgentsTab, 'push');
            app.RemoveAgentButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveAgentButtonPushed, true);
            app.RemoveAgentButton.Text = 'Remove Agent';
            app.AddAgentsaroundacirclePanel = uipanel(app.AgentsTab);
            app.AddAgentsaroundacirclePanel.Title = 'Add Agents around a circle';
            app.RadiusEditField_2Label = uilabel(app.AddAgentsaroundacirclePanel);
            app.RadiusEditField_2Label.Text = 'Radius';
            app.collective_add_agents_radius = uieditfield(app.AddAgentsaroundacirclePanel, 'numeric');
            app.ofAgentsEditFieldLabel = uilabel(app.AddAgentsaroundacirclePanel);
            app.ofAgentsEditFieldLabel.Text = '# of Agents';
            app.collective_add_agents_number = uieditfield(app.AddAgentsaroundacirclePanel, 'numeric');
            app.collective_add_button = uibutton(app.AddAgentsaroundacirclePanel, 'push');
            app.collective_add_button.ButtonPushedFcn = createCallbackFcn(app, @collective_add_buttonButtonPushed, true);
            app.collective_add_button.Text = 'Add';
            app.XEditField_3Label_2 = uilabel(app.AddAgentsaroundacirclePanel);
            app.XEditField_3Label_2.Text = 'X:';
            app.collective_add_x_coordinate = uieditfield(app.AddAgentsaroundacirclePanel, 'numeric');
            app.YEditField_3Label_2 = uilabel(app.AddAgentsaroundacirclePanel);
            app.YEditField_3Label_2.Text = 'Y:';
            app.collective_add_y_coordinate = uieditfield(app.AddAgentsaroundacirclePanel, 'numeric');
            app.RmvallButton = uibutton(app.AgentsTab, 'push');
            app.RmvallButton.ButtonPushedFcn = createCallbackFcn(app, @RmvallButtonPushed, true);
            app.RmvallButton.Text = {'Rmv.'; ' all'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end