classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        MapTab                        matlab.ui.container.Tab
        OPTIONSPanel                  matlab.ui.container.Panel
        VoxelLengthSliderLabel        matlab.ui.control.Label
        VoxelLengthSlider             matlab.ui.control.Slider
        ofstepsEditFieldLabel         matlab.ui.control.Label
        ofstepsEditField              matlab.ui.control.NumericEditField
        StartSimulatorButton          matlab.ui.control.Button
        DeltaTSliderLabel             matlab.ui.control.Label
        DeltaTSlider                  matlab.ui.control.Slider
        ofparticlesEditFieldLabel     matlab.ui.control.Label
        ofparticlesEditField          matlab.ui.control.NumericEditField
        RemainingStepsLabel           matlab.ui.control.Label
        remaining_steps               matlab.ui.control.Label
        AnimationScaleLabel           matlab.ui.control.Label
        AnimationScaleSlider          matlab.ui.control.Slider
        ofparticlesLabel              matlab.ui.control.Label
        ofParticles                   matlab.ui.control.Label
        AdvancebyEditFieldLabel       matlab.ui.control.Label
        AdvancebyEditField            matlab.ui.control.NumericEditField
        AdvanceButton                 matlab.ui.control.Button
        SourceVoxelCoordinatesLabel   matlab.ui.control.Label
        XEditFieldLabel               matlab.ui.control.Label
        XEditField                    matlab.ui.control.NumericEditField
        YEditFieldLabel               matlab.ui.control.Label
        YEditField                    matlab.ui.control.NumericEditField
        DEditFieldLabel               matlab.ui.control.Label
        DEditField                    matlab.ui.control.NumericEditField
        Panel_2                       matlab.ui.container.Panel
        SizeofGridEditFieldLabel      matlab.ui.control.Label
        SizeofGridEditField           matlab.ui.control.NumericEditField
        CalculatingMatricesLabel      matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        FlowMapTab                    matlab.ui.container.Tab
        FlowOptionsButtonGroup        matlab.ui.container.ButtonGroup
        Switch                        matlab.ui.control.Switch
        Panel                         matlab.ui.container.Panel
        FlowMagnitudeLabel            matlab.ui.control.Label
        FlowMagnitudeSlider           matlab.ui.control.Slider
        KnobLabel                     matlab.ui.control.Label
        Knob                          matlab.ui.control.Knob
        SetFlowButton                 matlab.ui.control.Button
        Panel_3                       matlab.ui.container.Panel
        UIAxes_2                      matlab.ui.control.UIAxes
        AngleChangeFactorEditFieldLabel  matlab.ui.control.Label
        AngleChangeFactorEditField    matlab.ui.control.NumericEditField
        MagnitudeDecreaseFactorLabel  matlab.ui.control.Label
        MagnitudeDecreaseFactorEditField  matlab.ui.control.NumericEditField
    end

    
    methods (Access = public)
        
        function result = set_gauge_value(app,value)
            if value==-1
               app.CalculatingMatricesLabel.Visible = "on";
            elseif value == -2
                app.CalculatingMatricesLabel.Visible = "off";
            else
                app.CalculatingMatricesLabel.Text = "Calculating Matrices :%"+ string(value);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            global meshsize_x meshsize_y flow_matrix restart particles flow_matrix_x_positive flow_matrix_x_negative flow_matrix_y_positive flow_matrix_y_negative flow_matrix_stay;
            meshsize_x = app.SizeofGridEditField.Value;
            meshsize_y = app.SizeofGridEditField.Value;
            flow_matrix= zeros(meshsize_x,meshsize_y,2);
            restart = true;
            particles = zeros(meshsize_x,meshsize_y);
            app.Panel.Visible = 'off' ;
            app.XEditField.Value = floor(app.SizeofGridEditField.Value/2);
            app.YEditField.Value = floor(app.SizeofGridEditField.Value/2);
            app.CalculatingMatricesLabel.Visible = 'off';
            flow_matrix_x_positive = zeros(meshsize_x,meshsize_y);
            flow_matrix_x_negative = zeros(meshsize_x,meshsize_y);
            flow_matrix_y_positive = zeros(meshsize_x,meshsize_y);
            flow_matrix_y_negative = zeros(meshsize_x,meshsize_y);
            flow_matrix_stay = zeros(meshsize_x,meshsize_y);
        end

        % Button pushed function: StartSimulatorButton
        function StartSimulatorButtonPushed(app, event)
                set_gauge_value(app,-1);
            global meshsize_x meshsize_y flow_matrix restart particles flow_matrix_x_positive flow_matrix_x_negative flow_matrix_y_positive flow_matrix_y_negative flow_matrix_stay;
                L_voxel = app.VoxelLengthSlider.Value;
                D       = app.DEditField.Value;
                delta_t = app.DeltaTSlider.Value ;
                env_number_of_particles = app.ofparticlesEditField.Value;
                particles = zeros(meshsize_x,meshsize_y);
                particles(floor(meshsize_x/2),floor(meshsize_y/2))= env_number_of_particles;
                %temp_particles= particles;
                %number_of_steps = app.ofstepsEditField.Value;
            if restart
                [X,Y]=meshgrid(1:meshsize_x+1);
                if app.Switch.Value == "No Flow"
                    is_flow_active = false;
                else
                    is_flow_active=true;
                end
              
                flow_matrix_x_positive = zeros(meshsize_x,meshsize_y);
                flow_matrix_x_negative = zeros(meshsize_x,meshsize_y);
                flow_matrix_y_positive = zeros(meshsize_x,meshsize_y);
                flow_matrix_y_negative = zeros(meshsize_x,meshsize_y);
                flow_matrix_stay = zeros(meshsize_x,meshsize_y);
                set_gauge_value(app,-1); 
                
                %% Evaluate transition matrices once and for all
                for column=1:meshsize_y;
                    for row=meshsize_x:-1:1
                        [p_stay, p_trans] = eval_probs(is_flow_active,L_voxel,D,delta_t,flow_matrix,row,column);
                        flow_matrix_x_positive(row,column) = p_trans.x_positive;
                        flow_matrix_x_negative(row,column) = p_trans.x_negative;
                        flow_matrix_y_positive(row,column) = p_trans.y_positive;
                        flow_matrix_y_negative(row,column) = p_trans.y_negative;
                        flow_matrix_stay(row,column) = p_stay;
                    end
                    set_gauge_value(app,round((column/meshsize_y)  *100,2));
                    pause(0.001);
                end
                
                %%
                restart = false;
            end
            
            for i=app.ofstepsEditField.Value:-1:1
                A = particles.*flow_matrix_x_positive;
                B = particles.*flow_matrix_x_negative;
                C = particles.*flow_matrix_y_positive;
                D = particles.*flow_matrix_y_negative;
                E = particles.*flow_matrix_stay;
                particles = E + [zeros(1,meshsize_y) ; D(1:meshsize_x-1,:)] + [C(2:meshsize_x,:) ; zeros(1,meshsize_y)] + [ zeros(meshsize_x,1) , A(:, 1:meshsize_y-1)] + [B(:, 2:meshsize_y) , zeros(meshsize_x,1)];
                %disp(i)
                
                if app.AnimationScaleSlider.Value ~=  0
                    imagesc(app.UIAxes,particles)
                    colorbar
                    app.remaining_steps.Text = string(i);
                    app.ofParticles.Text = string(sum(particles, 'all'));
                    pause(0.2/app.AnimationScaleSlider.Value);
                end
            end
            imagesc(app.UIAxes,particles)
            colorbar
            app.remaining_steps.Text = string(0);
            app.ofParticles.Text = string(sum(particles, 'all'));
            set_gauge_value(app,-2);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            global meshsize_x meshsize_y flow_matrix;
            %This function gives a random flow matrix
            % matrix length is given as input
            disp(value)
            if value == "No Flow"
                
                flow_matrix= zeros(meshsize_x,meshsize_y,2);
                app.Panel.Visible = 'off' ;
            end
            if value == "With Flow"
                app.Panel.Visible = 'on' ;
                
            end
            
        end

        % Value changed function: SizeofGridEditField
        function SizeofGridEditFieldValueChanged(app, event)
            value = app.SizeofGridEditField.Value;
            global meshsize_x meshsize_y flow_matrix;
            meshsize_x = value;
            meshsize_y = value;
            flow_matrix= zeros(meshsize_x,meshsize_y,2);
            app.XEditField.Value = floor(app.SizeofGridEditField.Value/2);
            app.YEditField.Value = floor(app.SizeofGridEditField.Value/2);
            
        end

        % Size changed function: FlowMapTab
        function FlowMapTabSizeChanged(app, event)
            position = app.FlowMapTab.Position;
            
        end

        % Button pushed function: SetFlowButton
        function SetFlowButtonPushed(app, event)
            global meshsize_x meshsize_y flow_matrix restart;
            if app.Switch.Value == "With Flow"
                magnitude = app.FlowMagnitudeSlider.Value;
                disp(magnitude)
                magnitude_decrease_factor=app.MagnitudeDecreaseFactorEditField.Value;
                angle_change_factor=app.AngleChangeFactorEditField.Value;
                angle = app.Knob.Value ;
                sign = randi([-1, 1], 1) % Get a -1 or 1 randomly.
                
                flow_matrix(meshsize_x,meshsize_y,1)= magnitude;
                flow_matrix(meshsize_x,meshsize_y,2)= angle;
                
                for column=meshsize_y:-1:1
                    for row=meshsize_x:-1:1
                        if row==meshsize_x && column== meshsize_y
                            ;
                        elseif row==meshsize_x && column~= meshsize_y
                            flow_matrix(row,column,1)= flow_matrix(row,column+1,1);
                            flow_matrix(row,column,2)= flow_matrix(row,column+1,2)+(randi([-1, 1], 1)*2*pi*((angle_change_factor).*rand(1,1)));
                        elseif row ~= meshsize_x
                            flow_matrix(row,column,1)= flow_matrix(row+1,column,1)*(1+magnitude_decrease_factor * (0.1).*rand(1,1));
                            flow_matrix(row,column,2)= flow_matrix(row+1,column,2)+(randi([-1, 1], 1)*2*pi*((angle_change_factor).*rand(1,1)));
                        end
                    end
                end
            end
            disp(flow_matrix)
            [x,y] = meshgrid(1:meshsize_x);
            r = flow_matrix(:,:,1);
            theta = (flow_matrix(:,:,2)/360)*2*pi;
            u = r * cos(theta); % convert polar (theta,r) to cartesian
            v = r * sin(theta);
            quiver(app.UIAxes_2,x,y,u,v)
            restart = true;
            
        end

        % Button pushed function: AdvanceButton
        function AdvanceButtonPushed(app, event)
            global restart meshsize_x meshsize_y particles flow_matrix_x_positive flow_matrix_x_negative flow_matrix_y_positive flow_matrix_y_negative flow_matrix_stay;
            
            if ~restart
                app.remaining_steps.Text  = string(app.AdvancebyEditField.Value);
                for i=app.AdvancebyEditField.Value:-1:1
                    A = particles.*flow_matrix_x_positive;
                    B = particles.*flow_matrix_x_negative;
                    C = particles.*flow_matrix_y_positive;
                    D = particles.*flow_matrix_y_negative;
                    E = particles.*flow_matrix_stay;
                    particles = E + [zeros(1,meshsize_y) ; D(1:meshsize_x-1,:)] + [C(2:meshsize_x,:) ; zeros(1,meshsize_y)] + [ zeros(meshsize_x,1) , A(:, 1:meshsize_y-1)] + [B(:, 2:meshsize_y) , zeros(meshsize_x,1)];
                    %disp(i)
                    
                    if app.AnimationScaleSlider.Value ~=  0
                        imagesc(app.UIAxes,particles)
                        colorbar
                        app.remaining_steps.Text = string(i);
                        app.ofParticles.Text = string(sum(particles, 'all'));
                        pause(0.5/app.AnimationScaleSlider.Value);
                    end
                end
                imagesc(app.UIAxes,particles)
                colorbar
                app.remaining_steps.Text = string(0);
                app.ofParticles.Text = string(sum(particles, 'all'));
            end
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            value = app.XEditField.Value;
            if value > app.SizeofGridEditField.Value
            app.XEditField.Value = app.SizeofGridEditField.Value;
            app.YEditField.Value = app.SizeofGridEditField.Value;
            end
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            value = app.YEditField.Value;
            if value > app.SizeofGridEditField.Value
            app.XEditField.Value = app.SizeofGridEditField.Value;
            app.YEditField.Value = app.SizeofGridEditField.Value;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MapTab = uitab(app.TabGroup);
            app.MapTab.Title = 'Map';
            app.OPTIONSPanel = uipanel(app.MapTab);
            app.OPTIONSPanel.Title = 'OPTIONS';
            app.VoxelLengthSliderLabel = uilabel(app.OPTIONSPanel);
            app.VoxelLengthSliderLabel.Text = 'Voxel Length';
            app.VoxelLengthSlider = uislider(app.OPTIONSPanel);
            app.VoxelLengthSlider.Limits = [0.25 2];
            app.VoxelLengthSlider.Value = 0.25;
            app.ofstepsEditFieldLabel = uilabel(app.OPTIONSPanel);
            app.ofstepsEditFieldLabel.Text = '# of steps';
            app.ofstepsEditField = uieditfield(app.OPTIONSPanel, 'numeric');
            app.ofstepsEditField.ValueDisplayFormat = '%.0f';
            app.ofstepsEditField.Value = 1;
            app.StartSimulatorButton = uibutton(app.OPTIONSPanel, 'push');
            app.StartSimulatorButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulatorButtonPushed, true);
            app.StartSimulatorButton.Text = 'Start Simulator';
            app.DeltaTSliderLabel = uilabel(app.OPTIONSPanel);
            app.DeltaTSliderLabel.Text = 'Delta T';
            app.DeltaTSlider = uislider(app.OPTIONSPanel);
            app.DeltaTSlider.Limits = [0.001 0.01];
            app.DeltaTSlider.Value = 0.001;
            app.ofparticlesEditFieldLabel = uilabel(app.OPTIONSPanel);
            app.ofparticlesEditFieldLabel.Text = '# of particles';
            app.ofparticlesEditField = uieditfield(app.OPTIONSPanel, 'numeric');
            app.ofparticlesEditField.ValueDisplayFormat = '%.0f';
            app.ofparticlesEditField.Value = 100000;
            app.RemainingStepsLabel = uilabel(app.OPTIONSPanel);
            app.RemainingStepsLabel.Text = 'Remaining Steps: ';
            app.remaining_steps = uilabel(app.OPTIONSPanel);
            app.remaining_steps.Text = '0';
            app.AnimationScaleLabel = uilabel(app.OPTIONSPanel);
            app.AnimationScaleLabel.Text = {'Animation '; 'Scale'};
            app.AnimationScaleSlider = uislider(app.OPTIONSPanel);
            app.AnimationScaleSlider.Limits = [0 10];
            app.AnimationScaleSlider.Value = 1;
            app.ofparticlesLabel = uilabel(app.OPTIONSPanel);
            app.ofparticlesLabel.Text = '# of particles:';
            app.ofParticles = uilabel(app.OPTIONSPanel);
            app.ofParticles.Text = '0';
            app.AdvancebyEditFieldLabel = uilabel(app.OPTIONSPanel);
            app.AdvancebyEditFieldLabel.Text = 'Advance by';
            app.AdvancebyEditField = uieditfield(app.OPTIONSPanel, 'numeric');
            app.AdvancebyEditField.Value = 1;
            app.AdvanceButton = uibutton(app.OPTIONSPanel, 'push');
            app.AdvanceButton.ButtonPushedFcn = createCallbackFcn(app, @AdvanceButtonPushed, true);
            app.AdvanceButton.Text = 'Advance';
            app.SourceVoxelCoordinatesLabel = uilabel(app.OPTIONSPanel);
            app.SourceVoxelCoordinatesLabel.Text = 'Source Voxel Coordinates:';
            app.XEditFieldLabel = uilabel(app.OPTIONSPanel);
            app.XEditFieldLabel.Text = 'X:';
            app.XEditField = uieditfield(app.OPTIONSPanel, 'numeric');
            app.XEditField.ValueDisplayFormat = '%.0f';
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.OPTIONSPanel);
            app.YEditFieldLabel.Text = 'Y:';
            app.YEditField = uieditfield(app.OPTIONSPanel, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.DEditFieldLabel = uilabel(app.OPTIONSPanel);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.OPTIONSPanel, 'numeric');
            app.DEditField.Value = 7;
            app.Panel_2 = uipanel(app.MapTab);
            app.SizeofGridEditFieldLabel = uilabel(app.Panel_2);
            app.SizeofGridEditFieldLabel.Text = 'Size of Grid ';
            app.SizeofGridEditField = uieditfield(app.Panel_2, 'numeric');
            app.SizeofGridEditField.ValueChangedFcn = createCallbackFcn(app, @SizeofGridEditFieldValueChanged, true);
            app.SizeofGridEditField.Value = 100;
            app.UIAxes = uiaxes(app.Panel_2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, {'Y'; ''})
            app.CalculatingMatricesLabel = uilabel(app.MapTab);
            app.CalculatingMatricesLabel.Text = 'Calculating Matrices :% ';
            app.FlowMapTab = uitab(app.TabGroup);
            app.FlowMapTab.SizeChangedFcn = createCallbackFcn(app, @FlowMapTabSizeChanged, true);
            app.FlowMapTab.Title = 'Flow Map';
            app.FlowOptionsButtonGroup = uibuttongroup(app.FlowMapTab);
            app.FlowOptionsButtonGroup.Title = 'Flow Options';
            app.Switch = uiswitch(app.FlowOptionsButtonGroup, 'slider');
            app.Switch.Items = {'No Flow', 'With Flow'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'No Flow';
            app.Panel = uipanel(app.FlowMapTab);
            app.FlowMagnitudeLabel = uilabel(app.Panel);
            app.FlowMagnitudeLabel.Text = {'Flow '; 'Magnitude'};
            app.FlowMagnitudeSlider = uislider(app.Panel);
            app.FlowMagnitudeSlider.Limits = [0 400];
            app.KnobLabel = uilabel(app.Panel);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.Panel, 'continuous');
            app.Knob.Limits = [0 360];
            app.SetFlowButton = uibutton(app.FlowMapTab, 'push');
            app.SetFlowButton.ButtonPushedFcn = createCallbackFcn(app, @SetFlowButtonPushed, true);
            app.SetFlowButton.Text = 'Set Flow';
            app.Panel_3 = uipanel(app.FlowMapTab);
            app.UIAxes_2 = uiaxes(app.Panel_3);
            title(app.UIAxes_2, 'Flow Map')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.AngleChangeFactorEditFieldLabel = uilabel(app.Panel_3);
            app.AngleChangeFactorEditFieldLabel.Text = 'Angle Change Factor';
            app.AngleChangeFactorEditField = uieditfield(app.Panel_3, 'numeric');
            app.AngleChangeFactorEditField.Value = 0.0005;
            app.MagnitudeDecreaseFactorLabel = uilabel(app.Panel_3);
            app.MagnitudeDecreaseFactorLabel.Text = {'Magnitude '; 'Decrease Factor'};
            app.MagnitudeDecreaseFactorEditField = uieditfield(app.Panel_3, 'numeric');
            app.MagnitudeDecreaseFactorEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end